classdef InterfaceGraphique < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        statusLabel                   matlab.ui.control.Label
        UnitLabel                     matlab.ui.control.Label
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        valeurParametre               matlab.ui.control.EditField
        ValeurLabel                   matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        ChoixdeFiltrageDropDown       matlab.ui.control.DropDown
        ChoixdeFiltrageDropDownLabel  matlab.ui.control.Label
        DfinitionLabel                matlab.ui.control.Label
        paramtresderugositsurfaciqueListBox  matlab.ui.control.ListBox
        paramtresderugositsurfaciqueLabel  matlab.ui.control.Label
        AffihagedesdonnesButton       matlab.ui.control.Button
        SletionerlefichierButton      matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end


    properties (Access = public)
        Data % ensemble de données de la fonction aliconaReader()
        Depth_data_H % Z filtré par la fonction filloutliers()
        Z_data % Data.Depth de la fonction AliconaReader()
        X % Coordonnés X
        Y % Coordonnés Y
        fitresult % Description
        Sp % Description
        Sv % Description
        Sz % Description
        Sa % Description
        Sq % Description
        Ssk % Description
        Sku % Description
        Sds % Description
        mask % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SletionerlefichierButton
        function SletionerlefichierButtonPushed(app, event)

            app.Data = AliconaReader(); %Lire les données, il faut séléctionner les données à traiter
            %AliconaPlot(app.Data,app.UIAxes); %Affichage des resultats
            app.statusLabel.Text = "en train de charger les données";
            app.Z_data = app.Data.DepthData;
            depth_inter_median = double(filloutliers(app.Z_data,'nearest','median'));
            [app.X,app.Y] = meshgrid(1:1:length(app.Z_data));
            [xData, yData, zData] = prepareSurfaceData( app.X, app.Y, depth_inter_median );
            ft = fittype( 'poly11' );
            app.fitresult= fit( [xData, yData], zData, ft );
            % Dégauchissage du nuage de points
            paramtr=coeffvalues(app.fitresult);
            Z = paramtr(2)*app.X + paramtr(3)*app.Y + paramtr(1);
            app.Depth_data_H=depth_inter_median-Z;
            app.Sp = max(app.Depth_data_H(:));
            app.Sv = min(app.Depth_data_H(:));
            app.Sz = (abs(app.Sp) + abs(app.Sv));
            app.Sa = mean(mean(abs(app.Depth_data_H)));
            app.Sq = sqrt(sum(sum(app.Depth_data_H.^2))/length(app.Depth_data_H)^2);
            app.Ssk = mean(mean((app.Depth_data_H.^3)))/app.Sq^3 ;
            app.Sku = mean(mean((app.Depth_data_H.^4)))/app.Sq^4;
            app.mask = imregionalmax(app.Depth_data_H);
            app.Sds = floor(length(find(app.mask(:,:)==1))/(str2double(app.Data.Header.PixelSizeYMeter)*length(app.Depth_data_H))^2);
            app.statusLabel.Text = "les données sont prêtes";
        end

        % Button pushed function: AffihagedesdonnesButton
        function AffihagedesdonnesButtonPushed(app, event)

            paramtresderugositsurfaciqueListBoxValueChanged(app, event);
            mesh(app.Depth_data_H,'Parent',app.UIAxes)


        end

        % Value changed function: paramtresderugositsurfaciqueListBox
        function paramtresderugositsurfaciqueListBoxValueChanged(app, event)
            value = app.paramtresderugositsurfaciqueListBox.Value;
            switch value
                case 'Sp'
                    app.UnitLabel.Text = "m";
                    app.valeurParametre.Value = num2str(app.Sp);
                    app.DfinitionLabel.Text ="C'est la hauteur de la crête la plus haute dans la zone définie.";
                case 'Sv'
                    app.UnitLabel.Text = "m";
                    app.valeurParametre.Value = num2str(app.Sv);
                    app.DfinitionLabel.Text ="Plus grande des profondeurs de creux, à l'intérieur de l'aire de base." ;
                case 'Sz'
                    app.UnitLabel.Text = "m";
                    app.valeurParametre.Value = num2str(app.Sz);
                    app.DfinitionLabel.Text ="Somme de la hauteur maximale de pic et de la profondeur maximale de creux, à l'intérieur de l'aire de base.";
                case 'Sa'
                    app.UnitLabel.Text = "m";
                    app.valeurParametre.Value = num2str(app.Sa);
                    app.DfinitionLabel.Text ="Moyenne arithmétique des hauteurs Z (x, y), à l'intérieur de l'aire de base.";
                case 'Ssk'
                    app.UnitLabel.Text = "";
                    app.valeurParametre.Value = num2str(app.Ssk);
                    app.DfinitionLabel.Text ="Facteur d'asymétrie, Les valeurs Ssk représentent le degré de biais de la forme de la rugosité (aspérité)." + ...
                        "Ssk<0: La distribution de la hauteur est asymétrique au-dessus du plan moyen." + ...
                        "Ssk=0: La distribution de la hauteur (crêtes et puits) est symétrique autour du plan moyen." + ...
                        "Ssk>0: La distribution de la hauteur est asymétrique au-dessus du plan moyen.";
                case 'Sku'
                    app.UnitLabel.Text = "";
                    app.valeurParametre.Value = num2str(app.Sku);
                    app.DfinitionLabel.Text ="Facteur d'aplatissement, La valeur Sku est une mesure de l'aspect pointu du profil de rugosité." + ...
                        "Sku<3: La distribution de la hauteur est asymétrique au-dessus du plan moyen." + ...
                        "Sku=3: La distribution de la hauteur est normale. (les parties pointues et les parties indentées coexistent)" + ...
                        "Sku>3: La distribution de la hauteur est en pointes.";
                case 'Sds'
                    app.UnitLabel.Text = "piques";
                    app.valeurParametre.Value = num2str(app.Sds);
                    app.DfinitionLabel.Text ='Nombre de piques par unité de surface';
            end

        end

        % Value changed function: ChoixdeFiltrageDropDown
        function ChoixdeFiltrageDropDownValueChanged(app, event)
            value = app.ChoixdeFiltrageDropDown.Value;
            switch value
                case 'Sans Filtrage'
                    cla(app.UIAxes);
                    app.statusLabel.Text = "en cours de calcul";
                    app.Sp =max(app.Depth_data_H(:));
                    app.Sv = abs(min(app.Depth_data_H(:)));
                    app.Sz = (abs(app.Sp) + abs(app.Sv))/(length(app.Depth_data_H)*str2double(app.Data.Header.PixelSizeYMeter))^2;
                    app.Sa = mean(mean(abs(app.Depth_data_H)));
                    app.Sq = sqrt(sum(sum(app.Depth_data_H.^2))/length(app.Depth_data_H)^2);
                    app.Ssk = mean(mean((app.Depth_data_H.^3)))/app.Sq^3 ;
                    app.Sku = mean(mean((app.Depth_data_H.^4)))/app.Sq^4;
                    paramtresderugositsurfaciqueListBoxValueChanged(app, event);
                    app.Sds = floor(length(find(app.mask(:,:)==1))/(str2double(app.Data.Header.PixelSizeYMeter)*length(app.Depth_data_H))^2);
                    mesh(app.Depth_data_H,'Parent',app.UIAxes)
                    app.statusLabel.Text = "Le calcul est terminé";
                case 'Filtre de Gauss'
                    cla(app.UIAxes);
                    app.statusLabel.Text = "en cours de calcul";
                    FiltGauss_Z = imgaussfilt(app.Depth_data_H,0.9,"FilterSize",3);
                    app.Sp =max(FiltGauss_Z(:));
                    app.Sv = abs(min(FiltGauss_Z(:)));
                    app.Sz = (abs(app.Sp) + abs(app.Sv));
                    app.Sa = mean(mean(abs(FiltGauss_Z)));
                    app.Sq = sqrt(sum(sum(FiltGauss_Z.^2))/length(FiltGauss_Z)^2);
                    app.Ssk = mean(mean((FiltGauss_Z.^3)))/app.Sq^3 ;
                    app.Sku = mean(mean((FiltGauss_Z.^4)))/app.Sq^4;
                    paramtresderugositsurfaciqueListBoxValueChanged(app, event);
                    mesh(FiltGauss_Z,'Parent',app.UIAxes)
                    app.mask = imregionalmax(FiltGauss_Z);
                    app.Sds = floor(length(find(app.mask(:,:)==1))/(str2double(app.Data.Header.PixelSizeYMeter)*length(app.Depth_data_H))^2);
                    app.statusLabel.Text = "Le calcul est terminé";
                case 'Filtre moyenne'
                    cla(app.UIAxes);
                    app.statusLabel.Text = "en cours de calcul";
                    filter = fspecial('average',[3 3]);
                    FMoyenne_Z = imfilter(app.Depth_data_H,filter,'conv');
                    app.Sp =max(FMoyenne_Z(:));
                    app.Sv = abs(min(FMoyenne_Z(:)));
                    app.Sz = (abs(app.Sp) + abs(app.Sv));
                    app.Sa = mean(mean(abs(FMoyenne_Z)));
                    app.Sq = sqrt(sum(sum(FMoyenne_Z.^2))/length(FMoyenne_Z)^2);
                    app.Ssk = mean(mean((FMoyenne_Z.^3)))/app.Sq^3 ;
                    app.Sku = mean(mean((FMoyenne_Z.^4)))/app.Sq^4;
                    paramtresderugositsurfaciqueListBoxValueChanged(app, event);
                    mesh(FMoyenne_Z,'Parent',app.UIAxes)
                    app.mask = imregionalmax(FMoyenne_Z);
                    app.Sds = floor(length(find(app.mask(:,:)==1))/(str2double(app.Data.Header.PixelSizeYMeter)*length(app.Depth_data_H))^2);
                    app.statusLabel.Text = "Le calcul est terminé";
                case 'Filtre Morphologique'
                    cla(app.UIAxes);
                    app.statusLabel.Text = "en cours de calcul";
                    rayon = 3 ;  %rayon de la sphere en mètre.
                    h = 3 ;  % hauteur à afficher de la demi-sphere
                    %création d'un élément structurant en forme de sphère de
                    % rayon r et dont la hauteur maximale est h.
                    SE = offsetstrel('ball',rayon,h);
                    %matrice de projection de la demi-sphere sur le plan
                    Sphere= (ones(length(SE.Offset),length(SE.Offset))* ...
                        max(SE.Offset(:))-SE.Offset)*1e-6 ;
                    p=floor(length(Sphere)/2);
                    Offset = Z_data_H;  %déclaration de la matrice d'offset par dilataion
                    %la matrice sur la quelle on va prendre des imagettes
                    matrice_base = padarray(Z_data_H,[p p],-9e-10,'both');
                    for i=p+1:length(Z_data_H)-p
                        for j=p+1:length(Z_data_H)-p
                            im0=matrice_base(i-p:i+p,j-p:j+p); % Extraction de l'imagette
                            S = Sphere + Z_data_H(i,j);  %Matrice représent le contact entre
                            % la sphere et la surface
                            test = im0 >= S ;  % Matrice test si il y a un dépassement
                            % de la surface et la sphère
                            if max(test(:))==1 %présence d'un dépassement
                                Offset(i,j)=Offset(i,j)+max(max(im0-S));
                            end
                        end
                    end
                    app.mask = imregionalmax(Offset);
                    mesh(Offset);
                    app.Sp =max(Offset(:));
                    app.Sv = abs(min(Offset(:)));
                    app.Sz = (app.Sp) + (app.Sv);
                    app.Sa = mean(mean(abs(Offset)));
                    app.Sq = sqrt(sum(sum(Offset.^2))/length(Offset)^2);
                    app.Ssk = mean(mean((Offset.^3)))/app.Sq^3 ;
                    app.Sku = mean(mean((Offset.^4)))/app.Sq^4;
                    paramtresderugositsurfaciqueListBoxValueChanged(app, event);
                    mesh(Offset,'Parent',app.UIAxes)
                    app.mask = imregionalmax(Offset);
                    app.Sds = floor(length(find(app.mask(:,:)==1))/(str2double(app.Data.Header.PixelSizeYMeter)*length(app.Depth_data_H))^2);
                    app.statusLabel.Text = "Le calcul est terminé";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Surface')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SletionerlefichierButton = uibutton(app.UIFigure, 'push');
            app.SletionerlefichierButton.ButtonPushedFcn = createCallbackFcn(app, @SletionerlefichierButtonPushed, true);
            app.SletionerlefichierButton.Text = 'Séletioner le fichier ';
            app.AffihagedesdonnesButton = uibutton(app.UIFigure, 'push');
            app.AffihagedesdonnesButton.ButtonPushedFcn = createCallbackFcn(app, @AffihagedesdonnesButtonPushed, true);
            app.AffihagedesdonnesButton.Text = 'Affihage des données';
            app.paramtresderugositsurfaciqueLabel = uilabel(app.UIFigure);
            app.paramtresderugositsurfaciqueLabel.Interpreter = 'latex';
            app.paramtresderugositsurfaciqueLabel.Text = {'paramètres de rugosité '; 'surfacique'};
            app.paramtresderugositsurfaciqueListBox = uilistbox(app.UIFigure);
            app.paramtresderugositsurfaciqueListBox.Items = {'Sp', 'Sv', 'Sz', 'Sa', 'Ssk', 'Sku', 'Sds'};
            app.paramtresderugositsurfaciqueListBox.ValueChangedFcn = createCallbackFcn(app, @paramtresderugositsurfaciqueListBoxValueChanged, true);
            app.paramtresderugositsurfaciqueListBox.Value = 'Sp';
            app.DfinitionLabel = uilabel(app.UIFigure);
            app.DfinitionLabel.Interpreter = 'html';
            app.DfinitionLabel.Text = 'Définition';
            app.ChoixdeFiltrageDropDownLabel = uilabel(app.UIFigure);
            app.ChoixdeFiltrageDropDownLabel.Text = 'Choix de Filtrage';
            app.ChoixdeFiltrageDropDown = uidropdown(app.UIFigure);
            app.ChoixdeFiltrageDropDown.Items = {'Sans Filtrage', 'Filtre de Gauss', 'Filtre moyenne', 'Filtre Morphologique'};
            app.ChoixdeFiltrageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoixdeFiltrageDropDownValueChanged, true);
            app.ChoixdeFiltrageDropDown.Value = 'Sans Filtrage';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ENSPS_UPSAY_logo_couleur_2.png');
            app.ValeurLabel = uilabel(app.UIFigure);
            app.ValeurLabel.Text = 'Valeur';
            app.valeurParametre = uieditfield(app.UIFigure, 'text');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'folder.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '3501061.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'filtre.png');
            app.UnitLabel = uilabel(app.UIFigure);
            app.UnitLabel.Text = 'Unité';
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Interpreter = 'html';
            app.statusLabel.Text = 'status';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.DfinitionLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceGraphique

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end